classdef SW420IMPORTER < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        SW420CapsulesImporterToolUIFigure  matlab.ui.Figure
        SelectimportpathsLabel       matlab.ui.control.Label
        BrowseButton                 matlab.ui.control.Button
        SelectdestinationpathLabel   matlab.ui.control.Label
        CapsulesTextAreaLabel        matlab.ui.control.Label
        CapsulesTextArea             matlab.ui.control.TextArea
        BrowseButton_2               matlab.ui.control.Button
        ImportersettingsLabel        matlab.ui.control.Label
        SettingsButton               matlab.ui.control.Button
        ConvertMF4logstoMATLabel     matlab.ui.control.Label
        Switch                       matlab.ui.control.Switch
        TextArea                     matlab.ui.control.TextArea
        StartLabel                   matlab.ui.control.Label
        StartButton                  matlab.ui.control.Button
        Label                        matlab.ui.control.Label
        backgroundcolor              matlab.ui.control.Label
        maxtime                      matlab.ui.control.NumericEditField
        maxfiles                     matlab.ui.control.NumericEditField
        MaxAllowanceTimeDifferenceLabel  matlab.ui.control.Label
        NormalMaxNumberofFilesLabel  matlab.ui.control.Label
        RequiredFilesPanel           matlab.ui.container.Panel
        ITS1CheckBox                 matlab.ui.control.CheckBox
        ITS2CheckBox                 matlab.ui.control.CheckBox
        ITS3CheckBox                 matlab.ui.control.CheckBox
        ITS4CheckBox                 matlab.ui.control.CheckBox
        FCamCheckBox                 matlab.ui.control.CheckBox
        FRadCheckBox                 matlab.ui.control.CheckBox
        VIDEOCheckBox                matlab.ui.control.CheckBox
        GPSCheckBox                  matlab.ui.control.CheckBox
        FCaviCheckBox                matlab.ui.control.CheckBox
        RCaviCheckBox                matlab.ui.control.CheckBox
        CONTEXTEaviCheckBox          matlab.ui.control.CheckBox
        FRaviCheckBox                matlab.ui.control.CheckBox
        FLaviCheckBox                matlab.ui.control.CheckBox
        matCheckBox                  matlab.ui.control.CheckBox
    end

    
    properties (Access = private)
        value % Description
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: BrowseButton
        function BrowseButtonPushed(app, event)
            import_from = 'C:\Users\p126620\OneDrive - Alliance\Bureau'; % Initial path
            destination_root = 'NaN'; % Output path
            required_files = {'ITS1', 'ITS2', 'ITS4', 'FCam', 'FRad', '_FC.avi', '_RC.avi'}; % Necessary files to examine
            allowable_time_diff = 3; % Allowable time difference in second
            normal_max_files = 16; % Check if the number of files exceed


            start_path = pwd; 
            dialog_title = 'select';

            import java.awt.*;
            import javax.swing.*;
            
            jchooser = javax.swing.JFileChooser(start_path);
            jchooser.setFileSelectionMode(JFileChooser.DIRECTORIES_ONLY);
            jchooser.setMultiSelectionEnabled(true);
            jchooser.setDialogTitle(dialog_title);
            if jchooser.showOpenDialog([]) == JFileChooser.APPROVE_OPTION
                jfiles = jchooser.getSelectedFiles();
                folders = arrayfun(@(f) char(f.getPath()), jfiles, 'UniformOutput', false);
            else
                folders = {};
            end


                    timestamp_pattern = '\d{8}_\d{6}'; % Time stamp format yyyymmdd_HHMMSS
            file_count = 0;
            timestamps = containers.Map();
            for i = 1:length(folders)
                folder = folders{i};
                files = dir(fullfile(folder, '**', '*.*'));
                for j = 1:length(files)
                    file = files(j);
                    if ~file.isdir
                        file_count = file_count + 1;
                        [~, name, ~] = fileparts(file.name);
                        matches = regexp(name, timestamp_pattern, 'match');
                        if ~isempty(matches)
                            timestamp = matches{1};
                            timestamp_num = datenum(timestamp, 'yyyymmdd_HHMMSS');
                            found = false;
                            keys = timestamps.keys;
                            for k = 1:length(keys)
                                existing_timestamp = keys{k};
                                existing_timestamp_num = datenum(existing_timestamp, 'yyyymmdd_HHMMSS');
                                if abs(timestamp_num - existing_timestamp_num) * 24 * 60 * 60 <= allowable_time_diff
                                    timestamp = existing_timestamp;
                                    found = true;
                                    break;
                                end
                            end
                            if isKey(timestamps, timestamp)
                                timestamps(timestamp) = [timestamps(timestamp), fullfile(file.folder, file.name)];
                            else
                                timestamps(timestamp) = {fullfile(file.folder, file.name)};
                            end
                        end
                    end
                end
            end
        if ~isempty(timestamps.keys)
            app.CapsulesTextArea.Value = strjoin(timestamps.keys, newline);
        else
            app.CapsulesTextArea.Value = 'NaN';
        end
        
        app.value.keys = keys;
        app.value.timestamps = timestamps;
        app.value.timestamp = timestamp;
        app.value.destination_root = destination_root;
        app.value.normal_max_files = normal_max_files;
        app.value.required_files = required_files;
        app.value.files = files;
            
            
        end

        % Button pushed function: BrowseButton_2
        function BrowseButton_2Pushed(app, event)
            folder = uigetdir;
            if folder ~= 0
                app.Label.Text = folder;
                app.value.destination_root = folder;
            end        
        end

        % Button pushed function: StartButton
        function StartButtonPushed(app, event)
            keys = app.value.keys;
            timestamps = app.value.timestamps;
            timestamp = app.value.timestamp;
            destination_root = app.value.destination_root;
            normal_max_files = app.value.normal_max_files;
            required_files = app.value.required_files;
            files = app.value.files;
            
            
            keys = timestamps.keys;
            for i = 1:length(keys)
                timestamp = keys{i};
                files = timestamps(timestamp);
                dest_folder = fullfile(destination_root, timestamp);
            
                if exist(dest_folder, 'dir') % check if the capsule already exists
                    message = ['Folder ', dest_folder, ' already exists. Skipping.'];
                    app.TextArea.Value = [app.TextArea.Value; {message}];
                    continue;
                end
            
                mkdir(dest_folder);
                for j = 1:length(files)
                    copyfile(files{j}, dest_folder);
                end
            
                copied_files = dir(fullfile(dest_folder, '*.*')); 
                if length(copied_files) > normal_max_files % check if there are files more than normal
                    message = ['Capsule ', timestamp, ' files more than normal.'];
                    app.TextArea.Value = [app.TextArea.Value; {message}];
                end
            
                missing_files = {};
                for j = 1:length(required_files) % check if there is important file missing
                    if isempty(dir(fullfile(dest_folder, ['*', required_files{j}, '*'])))
                        missing_files{end+1} = required_files{j};
                    end
                end
            
                if ~isempty(missing_files)
                    message = ['Capsule ', timestamp, ' is missing files: ', strjoin(missing_files, ', ')];
                    app.TextArea.Value = [app.TextArea.Value; {message}];
                    movefile(dest_folder, [dest_folder, '_incomplete']);
                end
            end
            app.TextArea.Value = [app.TextArea.Value; {'Done!'}];
        end

        % Button pushed function: SettingsButton
        function SettingsButtonPushed(app, event)
            currentPosition = app.SW420CapsulesImporterToolUIFigure.Position;
            if isequal(currentPosition(3:4), [728, 505])
                app.SW420CapsulesImporterToolUIFigure.Position(3:4) = [470, 505];
            else
                app.SW420CapsulesImporterToolUIFigure.Position(3:4) = [728, 505];
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.SW420CapsulesImporterToolUIFigure = uifigure('Visible', 'off');
            app.SW420CapsulesImporterToolUIFigure.Name = 'SW420 Capsules Importer Tool';
            app.SelectimportpathsLabel = uilabel(app.SW420CapsulesImporterToolUIFigure);
            app.SelectimportpathsLabel.Text = '1. Select import paths';
            app.BrowseButton = uibutton(app.SW420CapsulesImporterToolUIFigure, 'push');
            app.BrowseButton.ButtonPushedFcn = createCallbackFcn(app, @BrowseButtonPushed, true);
            app.BrowseButton.Text = 'Browse';
            app.SelectdestinationpathLabel = uilabel(app.SW420CapsulesImporterToolUIFigure);
            app.SelectdestinationpathLabel.Text = '2.  Select destination path';
            app.CapsulesTextAreaLabel = uilabel(app.SW420CapsulesImporterToolUIFigure);
            app.CapsulesTextAreaLabel.Text = 'Capsules';
            app.CapsulesTextArea = uitextarea(app.SW420CapsulesImporterToolUIFigure);
            app.BrowseButton_2 = uibutton(app.SW420CapsulesImporterToolUIFigure, 'push');
            app.BrowseButton_2.ButtonPushedFcn = createCallbackFcn(app, @BrowseButton_2Pushed, true);
            app.BrowseButton_2.Text = 'Browse';
            app.ImportersettingsLabel = uilabel(app.SW420CapsulesImporterToolUIFigure);
            app.ImportersettingsLabel.Text = '3. Importer settings';
            app.SettingsButton = uibutton(app.SW420CapsulesImporterToolUIFigure, 'push');
            app.SettingsButton.ButtonPushedFcn = createCallbackFcn(app, @SettingsButtonPushed, true);
            app.SettingsButton.Text = 'Settings';
            app.ConvertMF4logstoMATLabel = uilabel(app.SW420CapsulesImporterToolUIFigure);
            app.ConvertMF4logstoMATLabel.Text = '4. Convert MF4 logs to MAT';
            app.Switch = uiswitch(app.SW420CapsulesImporterToolUIFigure, 'slider');
            app.TextArea = uitextarea(app.SW420CapsulesImporterToolUIFigure);
            app.StartLabel = uilabel(app.SW420CapsulesImporterToolUIFigure);
            app.StartLabel.Text = '5. Start';
            app.StartButton = uibutton(app.SW420CapsulesImporterToolUIFigure, 'push');
            app.StartButton.ButtonPushedFcn = createCallbackFcn(app, @StartButtonPushed, true);
            app.StartButton.Text = 'Start';
            app.Label = uilabel(app.SW420CapsulesImporterToolUIFigure);
            app.Label.Text = '';
            app.backgroundcolor = uilabel(app.SW420CapsulesImporterToolUIFigure);
            app.backgroundcolor.Text = '';
            app.maxtime = uieditfield(app.SW420CapsulesImporterToolUIFigure, 'numeric');
            app.maxtime.Value = 3;
            app.maxfiles = uieditfield(app.SW420CapsulesImporterToolUIFigure, 'numeric');
            app.maxfiles.Value = 16;
            app.MaxAllowanceTimeDifferenceLabel = uilabel(app.SW420CapsulesImporterToolUIFigure);
            app.MaxAllowanceTimeDifferenceLabel.Text = 'Max Allowance Time Difference ';
            app.NormalMaxNumberofFilesLabel = uilabel(app.SW420CapsulesImporterToolUIFigure);
            app.NormalMaxNumberofFilesLabel.Text = 'Normal Max Number of Files';
            app.RequiredFilesPanel = uipanel(app.SW420CapsulesImporterToolUIFigure);
            app.RequiredFilesPanel.Title = 'Required Files';
            app.ITS1CheckBox = uicheckbox(app.RequiredFilesPanel);
            app.ITS1CheckBox.Text = 'ITS1';
            app.ITS1CheckBox.Value = true;
            app.ITS2CheckBox = uicheckbox(app.RequiredFilesPanel);
            app.ITS2CheckBox.Text = 'ITS2';
            app.ITS2CheckBox.Value = true;
            app.ITS3CheckBox = uicheckbox(app.RequiredFilesPanel);
            app.ITS3CheckBox.Text = 'ITS3';
            app.ITS4CheckBox = uicheckbox(app.RequiredFilesPanel);
            app.ITS4CheckBox.Text = 'ITS4';
            app.ITS4CheckBox.Value = true;
            app.FCamCheckBox = uicheckbox(app.RequiredFilesPanel);
            app.FCamCheckBox.Text = 'FCam';
            app.FCamCheckBox.Value = true;
            app.FRadCheckBox = uicheckbox(app.RequiredFilesPanel);
            app.FRadCheckBox.Text = 'FRad';
            app.FRadCheckBox.Value = true;
            app.VIDEOCheckBox = uicheckbox(app.RequiredFilesPanel);
            app.VIDEOCheckBox.Text = 'VIDEO';
            app.GPSCheckBox = uicheckbox(app.RequiredFilesPanel);
            app.GPSCheckBox.Text = 'GPS';
            app.FCaviCheckBox = uicheckbox(app.RequiredFilesPanel);
            app.FCaviCheckBox.Text = 'FC.avi';
            app.FCaviCheckBox.Value = true;
            app.RCaviCheckBox = uicheckbox(app.RequiredFilesPanel);
            app.RCaviCheckBox.Text = 'RC.avi';
            app.RCaviCheckBox.Value = true;
            app.CONTEXTEaviCheckBox = uicheckbox(app.RequiredFilesPanel);
            app.CONTEXTEaviCheckBox.Text = 'CONTEXTE.avi';
            app.FRaviCheckBox = uicheckbox(app.RequiredFilesPanel);
            app.FRaviCheckBox.Text = 'FR.avi';
            app.FLaviCheckBox = uicheckbox(app.RequiredFilesPanel);
            app.FLaviCheckBox.Text = 'FL.avi';
            app.matCheckBox = uicheckbox(app.RequiredFilesPanel);
            app.matCheckBox.Text = '.mat';
            % Show the figure after all components are created
            app.SW420CapsulesImporterToolUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = SW420IMPORTER

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.SW420CapsulesImporterToolUIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.SW420CapsulesImporterToolUIFigure)
        end
    end
end